classdef BC_VARETA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BCVARETAUIFigure         matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        ExitMenu                 matlab.ui.container.Menu
        ToolsMenu                matlab.ui.container.Menu
        CreateDataStructureMenu  matlab.ui.container.Menu
        SingleSubjectMenu        matlab.ui.container.Menu
        DataBatchingMenu         matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        TextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        single_subject % Description
    end
    
    methods (Access = private)
        
        function setPromptFcn(app,jTextArea,eventData,newPrompt)
            % Prevent overlapping reentry due to prompt replacement
            persistent inProgress
            if isempty(inProgress)
                inProgress = 1;  %#ok unused
            else
                return;
            end
            
            try
                % *** Prompt modification code goes here ***
                cwText = char(jTextArea.getText);
                app.TextArea.Value = cwText;
                % force prompt-change callback to fizzle-out...
                pause(0.02);
            catch
                % Never mind - ignore errors...
            end
            
            % Enable new callbacks now that the prompt has been modified
            inProgress = [];
            
        end  % setPromptFcn
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            warning off;
            addpath('guide');
            addpath('tools');
            addpath('functions');
            addpath('properties');
            try                
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
        end

        % Menu selected function: DataBatchingMenu
        function DataBatchingMenuSelected(app, event)
            root_tab =  'properties';
            parameter_name = 'run_single_subject';
            parameter_value = 0;
            change_xml_parameter(strcat('properties',filesep,'properties.xml'),root_tab,parameter_name,parameter_value);
            Main;
            msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: CreateDataStructureMenu
        function CreateDataStructureMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Source Folder');
            if(folder==0)
                return;
            end
            create_data_structure(folder);
            msgbox('Completed operation!!!','Info');
        end

        % Callback function
        function ButtonPushed(app, event)
            jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            jCmdWin = jDesktop.getClient('Command Window');
            jTextArea = jCmdWin.getComponent(0).getViewport.getView;
            cwText = char(jTextArea.getText);
            
            set(jTextArea,'CaretUpdateCallback',@myUpdateFcn)
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: SingleSubjectMenu
        function SingleSubjectMenuSelected(app, event)
            
            root_tab =  'properties';
            parameter_name = 'run_single_subject';
            parameter_value = 1;
            change_xml_parameter(strcat('properties',filesep,'properties.xml'),root_tab,parameter_name,parameter_value);
            Main;
            msgbox('Completed operation!!!','Info');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BCVARETAUIFigure = uifigure;
            app.BCVARETAUIFigure.Name = 'BC-VARETA';
            app.FileMenu = uimenu(app.BCVARETAUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.BCVARETAUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CreateDataStructureMenu = uimenu(app.ToolsMenu);
            app.CreateDataStructureMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateDataStructureMenuSelected, true);
            app.CreateDataStructureMenu.Text = 'Create Data Structure';
            app.SingleSubjectMenu = uimenu(app.ToolsMenu);
            app.SingleSubjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SingleSubjectMenuSelected, true);
            app.SingleSubjectMenu.Text = 'Single Subject';
            app.DataBatchingMenu = uimenu(app.ToolsMenu);
            app.DataBatchingMenu.MenuSelectedFcn = createCallbackFcn(app, @DataBatchingMenuSelected, true);
            app.DataBatchingMenu.Text = 'Data Batching';
            app.HelpMenu = uimenu(app.BCVARETAUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TextArea = uitextarea(app.BCVARETAUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BC_VARETA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BCVARETAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BCVARETAUIFigure)
        end
    end
end